classdef initialize_app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ApplyButton                  matlab.ui.control.Button
        OperationDropDown            matlab.ui.control.DropDown
        OperationDropDownLabel       matlab.ui.control.Label
        ImageDisplayPanel            matlab.ui.container.Panel
        InputImage                   matlab.ui.control.UIAxes
        OutputImage                  matlab.ui.control.UIAxes
        OperationTabGroup            matlab.ui.container.TabGroup
        ConvolutionTab               matlab.ui.container.Tab
        Label4                       matlab.ui.control.Label
        Label3                       matlab.ui.control.Label
        Label2                       matlab.ui.control.Label
        Label1                       matlab.ui.control.Label
        MaskList                     matlab.ui.control.Image
        MaskDropDown                 matlab.ui.control.DropDown
        MaskDropDownLabel            matlab.ui.control.Label
        SmoothingBlurringTab         matlab.ui.container.Tab
        TabGroup                     matlab.ui.container.TabGroup
        MeanFilterTab                matlab.ui.container.Tab
        MF_mask_size                 matlab.ui.control.NumericEditField
        MaskSzeLabel                 matlab.ui.control.Label
        GaussianFilterTab            matlab.ui.container.Tab
        sigma                        matlab.ui.control.NumericEditField
        MaskSzeLabel_4               matlab.ui.control.Label
        GF_mask_size                 matlab.ui.control.NumericEditField
        MaskSzeLabel_3               matlab.ui.control.Label
        ILPFTab                      matlab.ui.container.Tab
        ILPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_5               matlab.ui.control.Label
        BLPFTab                      matlab.ui.container.Tab
        BLPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_7               matlab.ui.control.Label
        BLPF_mask_size               matlab.ui.control.NumericEditField
        MaskSzeLabel_6               matlab.ui.control.Label
        GLPFTab                      matlab.ui.container.Tab
        GLPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_8               matlab.ui.control.Label
        HPFTab                       matlab.ui.container.Tab
        TabGroup_2                   matlab.ui.container.TabGroup
        IHPFTab_2                    matlab.ui.container.Tab
        IHPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_20              matlab.ui.control.Label
        BHPFTab_2                    matlab.ui.container.Tab
        BHPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_22              matlab.ui.control.Label
        BHPF_mask_size               matlab.ui.control.NumericEditField
        MaskSzeLabel_21              matlab.ui.control.Label
        GHPFTab_2                    matlab.ui.container.Tab
        GHPF_D0                      matlab.ui.control.NumericEditField
        MaskSzeLabel_23              matlab.ui.control.Label
        No4Tab                       matlab.ui.container.Tab
        metode                       matlab.ui.control.Label
        No4_ImageDropDown            matlab.ui.control.DropDown
        ImageDropDownLabel           matlab.ui.control.Label
        RestorationTab               matlab.ui.container.Tab
        NoiseDropDown                matlab.ui.control.DropDown
        NoiseDropDownLabel           matlab.ui.control.Label
        TabGroup_3                   matlab.ui.container.TabGroup
        MinFilterTab                 matlab.ui.container.Tab
        MiF_mask_size_n              matlab.ui.control.NumericEditField
        MaskSzeLabel_32              matlab.ui.control.Label
        MiF_mask_size_m              matlab.ui.control.NumericEditField
        MaskSzeLabel_31              matlab.ui.control.Label
        Mif_density                  matlab.ui.control.NumericEditField
        MaskSzeLabel_24              matlab.ui.control.Label
        MaxFilterTab                 matlab.ui.container.Tab
        MaF_mask_size_n              matlab.ui.control.NumericEditField
        MaskSzeLabel_35              matlab.ui.control.Label
        MaF_mask_size_m              matlab.ui.control.NumericEditField
        MaskSzeLabel_34              matlab.ui.control.Label
        MaF_density                  matlab.ui.control.NumericEditField
        MaskSzeLabel_33              matlab.ui.control.Label
        MedianFilterTab              matlab.ui.container.Tab
        MeF_mask_size_n              matlab.ui.control.NumericEditField
        MaskSzeLabel_41              matlab.ui.control.Label
        MeF_mask_size_m              matlab.ui.control.NumericEditField
        MaskSzeLabel_40              matlab.ui.control.Label
        MeF_density                  matlab.ui.control.NumericEditField
        MaskSzeLabel_39              matlab.ui.control.Label
        ArithmeticMeanFilterTab      matlab.ui.container.Tab
        AMF_mask_size_n              matlab.ui.control.NumericEditField
        MaskSzeLabel_47              matlab.ui.control.Label
        AMF_mask_size_m              matlab.ui.control.NumericEditField
        MaskSzeLabel_46              matlab.ui.control.Label
        AMF_density                  matlab.ui.control.NumericEditField
        MaskSzeLabel_45              matlab.ui.control.Label
        GeometricMeanFilterTab       matlab.ui.container.Tab
        GMF_mask_size_n              matlab.ui.control.NumericEditField
        MaskSzeLabel_50              matlab.ui.control.Label
        GMF_mask_size_m              matlab.ui.control.NumericEditField
        MaskSzeLabel_49              matlab.ui.control.Label
        GMF_density                  matlab.ui.control.NumericEditField
        MaskSzeLabel_48              matlab.ui.control.Label
        HarmonicMeanFilterTab        matlab.ui.container.Tab
        HMF_mask_size_n              matlab.ui.control.NumericEditField
        MaskSzeLabel_53              matlab.ui.control.Label
        HMF_mask_size_m              matlab.ui.control.NumericEditField
        MaskSzeLabel_52              matlab.ui.control.Label
        HMF_density                  matlab.ui.control.NumericEditField
        MaskSzeLabel_51              matlab.ui.control.Label
        ContraharmonicMeanFilterTab  matlab.ui.container.Tab
        CMF_Q                        matlab.ui.control.NumericEditField
        MaskSzeLabel_65              matlab.ui.control.Label
        CMF_mask_size_n              matlab.ui.control.NumericEditField
        MaskSzeLabel_56              matlab.ui.control.Label
        CMF_mask_size_m              matlab.ui.control.NumericEditField
        MaskSzeLabel_55              matlab.ui.control.Label
        CMF_density                  matlab.ui.control.NumericEditField
        MaskSzeLabel_54              matlab.ui.control.Label
        MidpointFilterTab            matlab.ui.container.Tab
        MPF_mask_size_n              matlab.ui.control.NumericEditField
        MaskSzeLabel_60              matlab.ui.control.Label
        MPF_mask_size_m              matlab.ui.control.NumericEditField
        MaskSzeLabel_59              matlab.ui.control.Label
        MPF_density                  matlab.ui.control.NumericEditField
        MaskSzeLabel_58              matlab.ui.control.Label
        AlphatrimmedMeanFilterTab    matlab.ui.container.Tab
        ATMF_d                       matlab.ui.control.NumericEditField
        MaskSzeLabel_64              matlab.ui.control.Label
        ATMF_mask_size_n             matlab.ui.control.NumericEditField
        MaskSzeLabel_63              matlab.ui.control.Label
        ATMF_mask_size_m             matlab.ui.control.NumericEditField
        MaskSzeLabel_62              matlab.ui.control.Label
        ATMF_density                 matlab.ui.control.NumericEditField
        MaskSzeLabel_61              matlab.ui.control.Label
        NotchFilterTab               matlab.ui.container.Tab
        ATMF_density_2               matlab.ui.control.NumericEditField
        MaskSzeLabel_66              matlab.ui.control.Label
        Notch_ImageDropDown          matlab.ui.control.DropDown
        ImageDropDown_2Label         matlab.ui.control.Label
        MotionDebluringTab           matlab.ui.container.Tab
        NSRSlider                    matlab.ui.control.Slider
        NSRSliderLabel               matlab.ui.control.Label
        PSFDropDown                  matlab.ui.control.DropDown
        PSFDropDownLabel             matlab.ui.control.Label
        SelectImageButton            matlab.ui.control.Button
        SelectedImageLabel           matlab.ui.control.Label
        ChooseImageLabel             matlab.ui.control.Label
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        pathToFile (1,1) string % Path to the currently loaded image file.
        pathToRefFile (1,1) string % Path to the reference image used for histogram specification.
    end
    
    
    methods (Access = private)
        
        function [Iout] = readImage(~, path)
            [Iout, palette] = imread(path);

            if ~isempty(palette)
                [M, N, ~] = size(Iout);
                C = size(palette, 2);
                IoutNonindexed = zeros(M, N, C);
                
                for i = 1 : M
                    for j = 1 : N
                        IoutNonindexed(i, j, :) = uint8(palette(Iout(i, j, 1) + 1, :) * 255);
                    end
                end

                Iout = IoutNonindexed;
                Iout = uint8(Iout);
            end
        end

        function [] = clearDisplays(comp)
            cla(comp.InputImage);
            cla(comp.OutputImage);

            cla(comp.InputHistogramR);
            cla(comp.InputHistogramG);
            cla(comp.InputHistogramB);

            cla(comp.OutputHistogramR);
            cla(comp.OutputHistogramG);
            cla(comp.OutputHistogramB);
        end

        function [] = displayHistogram(~, dispHist, axesR, axesG, axesB)
            cla(axesR);
            cla(axesG);
            cla(axesB);

            if size(dispHist, 1) == 1
                rCol = 'k';
                axesG.Visible = 'off';
                axesB.Visible = 'off';
            else
                rCol = 'r';
                axesG.Visible = 'on';
                axesB.Visible = 'on';
            end

            bar(axesR, 0:255, dispHist(1, :), 'FaceColor', rCol);
            if size(dispHist, 1) > 1
                bar(axesG, 0:255, dispHist(2, :), 'FaceColor', 'g');
                bar(axesB, 0:255, dispHist(3, :), 'FaceColor', 'b');
            end
        end

        function [] = displayInputHistogram(comp, dispHist)
            displayHistogram(comp, dispHist, comp.InputHistogramR, comp.InputHistogramG, comp.InputHistogramB);
        end

        function [] = displayOutputHistogram(comp, dispHist)
            displayHistogram(comp, dispHist, comp.OutputHistogramR, comp.OutputHistogramG, comp.OutputHistogramB);
        end

        function [] = brightnessRefreshPlot(comp)
            a = comp.BrightnessAField.Value;
            b = comp.BrightnessBField.Value;

            fnHandle = str2func(['@(x)min(max(', num2str(a), '*x+', num2str(b), ', 0), 255)']);
            plot(comp.ConvolutionPlot, 0:255, fnHandle(0:255));
        end
        
        function [] = negativeRefreshPlot(comp)
            if comp.NegativeEnableCheckBox.Value == 1
                fnHandle = @(x)255 - x;
            else
                fnHandle = @(x)x;
            end

            plot(comp.NegativePlot, 0:255, fnHandle(0:255));
        end

        function [] = logRefreshPlot(comp)
            c = comp.LogMultiplierField.Value;

            fnHandle = @(x)min(max(c * log10(double(1 + x)), 0), 255);
            plot(comp.LogPlot, 0:255, fnHandle(0:255));
        end

        function [] = powerRefreshPlot(comp)
            c = comp.PowerMultiplierField.Value;
            g = comp.PowerGammaField.Value;

            fnHandle = @(x)min(max(c * x .^ g, 0), 255);
            plot(comp.PowerPlot, 0:255, fnHandle(0:255));
        end

        function [] = equalizationShowPlot(comp, mapping)
            cla(comp.MappingPlot);
            plot(comp.MappingPlot, 0:255, mapping);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            brightnessRefreshPlot(comp);
            negativeRefreshPlot(comp);
            logRefreshPlot(comp);
            powerRefreshPlot(comp);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(comp, event)
            [fileName, fileLoc] = uigetfile({'*.bmp;*.tif;*.tiff', 'All files'; '*.bmp', 'BMP files'; '*.tif;*.tiff', 'TIFF files'});
            if fileLoc ~= 0
                clearDisplays(comp);

                comp.pathToFile = join([fileLoc, fileName], '\');
                comp.SelectedImageLabel.Text = comp.pathToFile;
                I = readImage(comp, comp.pathToFile);
                imshow(I, 'Parent', comp.InputImage);
    
                inputHist = make_histogram(I);
                displayInputHistogram(comp, inputHist);

                comp.ApplyButton.Enable = comp.pathToFile ~= '';
            end
        end

        % Selection change function: OperationTabGroup
        function OperationTabGroupSelectionChanged(comp, event)
            comp.OperationTabGroup.SelectedTab = event.OldValue;
        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(comp, event)
            comp.ApplyButton.Enable = comp.pathToFile ~= '';
            switch comp.OperationDropDown.Value
                case 'Brightness'
                    comp.OperationTabGroup.SelectedTab = comp.ConvolutionTab;
                case 'Negative'
                    comp.OperationTabGroup.SelectedTab = comp.SmoothingBlurringTab;
                case 'Logarithm transform'
                    comp.OperationTabGroup.SelectedTab = comp.LogTransformTab;
                case 'Power transform'
                    comp.OperationTabGroup.SelectedTab = comp.PowerTransformTab;
                case 'Contrast stretching'
                    comp.OperationTabGroup.SelectedTab = comp.ContrastStretchingTab;
                case 'Histogram equalization'
                    comp.OperationTabGroup.SelectedTab = comp.HistogramEqualizationTab;
                case 'Histogram matching'
                    comp.OperationTabGroup.SelectedTab = comp.HistogramMatchingTab;
                    comp.ApplyButton.Enable = comp.pathToFile ~= '' & comp.pathToRefFile ~= '';
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(comp, event)
            I = readImage(comp, comp.pathToFile);

            switch comp.OperationDropDown.Value
                case 'Brightness'
                    a = comp.BrightnessAField.Value;
                    b = comp.BrightnessBField.Value;
                    I2 = brightening(I, a, b);
                case 'Negative'
                    I2 = I;
                    if comp.NegativeEnableCheckBox.Value
                        I2 = negative(I2);
                    end
                case 'Logarithm transform'
                    c = comp.LogMultiplierField.Value;
                    I2 = logarithm(I, c);
                case 'Power transform'
                    c = comp.PowerMultiplierField.Value;
                    g = comp.PowerGammaField.Value;
                    I2 = power_gamma(I, c, g);
                case 'Contrast stretching'
                    [I2, minVal, maxVal] = contrast_stretching(I);
                    comp.CStretchingMinRField.Value = minVal(1);
                    comp.CStretchingMaxRField.Value = maxVal(1);
                    if size(minVal, 1) > 1
                        comp.CStretchingMinGField.Value = minVal(2);
                        comp.CStretchingMinBField.Value = minVal(3);

                        comp.CStretchingMaxGField.Value = maxVal(2);
                        comp.CStretchingMaxBField.Value = maxVal(3);

                        comp.CStretchingMinGField.Visible = 'on';
                        comp.CStretchingMinBField.Visible = 'on';
                        comp.CStretchingMaxGField.Visible = 'on';
                        comp.CStretchingMaxBField.Visible = 'on';
                    else
                        comp.CStretchingMinGField.Visible = 'off';
                        comp.CStretchingMinBField.Visible = 'off';
                        comp.CStretchingMaxGField.Visible = 'off';
                        comp.CStretchingMaxBField.Visible = 'off';
                    end
                case 'Histogram equalization'
                    [I2, mapping] = hist_equalization(I, 0);
                    equalizationShowPlot(comp, mapping);
                case 'Histogram matching'
                    Iref = readImage(comp, comp.pathToRefFile);
                    I2 = hist_specification(I, Iref);
            end

            outHist = make_histogram(I2);
            displayOutputHistogram(comp, outHist);
            imshow(I2, 'Parent', comp.OutputImage);
        end

        % Callback function
        function BrightnessAFieldValueChanged(comp, event)
            brightnessRefreshPlot(comp);
        end

        % Callback function
        function BrightnessBFieldValueChanged(comp, event)
            brightnessRefreshPlot(comp);
        end

        % Callback function
        function NegativeEnableCheckBoxValueChanged(comp, event)
            negativeRefreshPlot(comp);
        end

        % Callback function
        function LogMultiplierFieldValueChanged(comp, event)
            logRefreshPlot(comp);
        end

        % Callback function
        function PowerMultiplierFieldValueChanged(comp, event)
            powerRefreshPlot(comp);
        end

        % Callback function
        function PowerGammaFieldValueChanged(comp, event)
            powerRefreshPlot(comp);
        end

        % Callback function
        function ReferenceImageSelectButtonPushed(comp, event)
            [fileName, fileLoc] = uigetfile({'*.bmp;*.tif;*.tiff', 'All files'; '*.bmp', 'BMP files'; '*.tif;*.tiff', 'TIFF files'});
            if fileLoc ~= 0
                cla(comp.ReferenceHistogramR);
                cla(comp.ReferenceHistogramG);
                cla(comp.ReferenceHistogramB);

                comp.pathToRefFile = join([fileLoc, fileName], '\');
                I = readImage(comp, comp.pathToRefFile);
                imshow(I, 'Parent', comp.ReferenceImage);

                refHist = make_histogram(I);
                displayHistogram(comp, refHist, comp.ReferenceHistogramR, comp.ReferenceHistogramG, comp.ReferenceHistogramB);

                comp.ApplyButton.Enable = comp.pathToFile ~= '' & comp.pathToRefFile ~= '';
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 909 601];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.ChooseImageLabel = uilabel(comp);
            comp.ChooseImageLabel.Text = 'Choose image';
            comp.SelectedImageLabel = uilabel(comp);
            comp.SelectedImageLabel.Enable = 'off';
            comp.SelectedImageLabel.Text = 'No image selected';
            comp.SelectImageButton = uibutton(comp, 'push');
            comp.SelectImageButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectImageButtonPushed, true);
            comp.SelectImageButton.Text = 'Select';
            comp.OperationTabGroup = uitabgroup(comp);
            comp.OperationTabGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @OperationTabGroupSelectionChanged, true);
            comp.ConvolutionTab = uitab(comp.OperationTabGroup);
            comp.ConvolutionTab.Title = 'Convolution';
            comp.MaskDropDownLabel = uilabel(comp.ConvolutionTab);
            comp.MaskDropDownLabel.Text = 'Mask';
            comp.MaskDropDown = uidropdown(comp.ConvolutionTab);
            comp.MaskDropDown.Items = {'Mask 1', 'Mask 2', 'Mask 3', 'Mask 4'};
            comp.MaskDropDown.Value = 'Mask 3';
            comp.MaskList = uiimage(comp.ConvolutionTab);
            comp.MaskList.ImageSource = fullfile(pathToMLAPP, 'convolution_mask.png');
            comp.Label1 = uilabel(comp.ConvolutionTab);
            comp.Label1.Text = '1';
            comp.Label2 = uilabel(comp.ConvolutionTab);
            comp.Label2.Text = '2';
            comp.Label3 = uilabel(comp.ConvolutionTab);
            comp.Label3.Text = '3';
            comp.Label4 = uilabel(comp.ConvolutionTab);
            comp.Label4.Text = '4';
            comp.SmoothingBlurringTab = uitab(comp.OperationTabGroup);
            comp.SmoothingBlurringTab.Title = 'Smoothing/Blurring';
            comp.TabGroup = uitabgroup(comp.SmoothingBlurringTab);
            comp.MeanFilterTab = uitab(comp.TabGroup);
            comp.MeanFilterTab.Title = 'Mean Filter';
            comp.MaskSzeLabel = uilabel(comp.MeanFilterTab);
            comp.MaskSzeLabel.Text = 'Mask Size';
            comp.MF_mask_size = uieditfield(comp.MeanFilterTab, 'numeric');
            comp.MF_mask_size.Limits = [0 Inf];
            comp.MF_mask_size.Value = 106;
            comp.GaussianFilterTab = uitab(comp.TabGroup);
            comp.GaussianFilterTab.Title = 'Gaussian Filter';
            comp.MaskSzeLabel_3 = uilabel(comp.GaussianFilterTab);
            comp.MaskSzeLabel_3.Text = 'Mask Size';
            comp.GF_mask_size = uieditfield(comp.GaussianFilterTab, 'numeric');
            comp.GF_mask_size.Limits = [0 Inf];
            comp.GF_mask_size.Value = 106;
            comp.MaskSzeLabel_4 = uilabel(comp.GaussianFilterTab);
            comp.MaskSzeLabel_4.Text = 'sigma';
            comp.sigma = uieditfield(comp.GaussianFilterTab, 'numeric');
            comp.sigma.Limits = [0 Inf];
            comp.sigma.Value = 106;
            comp.ILPFTab = uitab(comp.TabGroup);
            comp.ILPFTab.Title = 'ILPF';
            comp.MaskSzeLabel_5 = uilabel(comp.ILPFTab);
            comp.MaskSzeLabel_5.Text = 'D0';
            comp.ILPF_D0 = uieditfield(comp.ILPFTab, 'numeric');
            comp.ILPF_D0.Limits = [0 Inf];
            comp.ILPF_D0.Value = 106;
            comp.BLPFTab = uitab(comp.TabGroup);
            comp.BLPFTab.Title = 'BLPF';
            comp.MaskSzeLabel_6 = uilabel(comp.BLPFTab);
            comp.MaskSzeLabel_6.Text = 'Mask Size';
            comp.BLPF_mask_size = uieditfield(comp.BLPFTab, 'numeric');
            comp.BLPF_mask_size.Limits = [0 Inf];
            comp.BLPF_mask_size.Value = 106;
            comp.MaskSzeLabel_7 = uilabel(comp.BLPFTab);
            comp.MaskSzeLabel_7.Text = 'D0';
            comp.BLPF_D0 = uieditfield(comp.BLPFTab, 'numeric');
            comp.BLPF_D0.Limits = [0 Inf];
            comp.BLPF_D0.Value = 106;
            comp.GLPFTab = uitab(comp.TabGroup);
            comp.GLPFTab.Title = 'GLPF';
            comp.MaskSzeLabel_8 = uilabel(comp.GLPFTab);
            comp.MaskSzeLabel_8.Text = 'D0';
            comp.GLPF_D0 = uieditfield(comp.GLPFTab, 'numeric');
            comp.GLPF_D0.Limits = [0 Inf];
            comp.GLPF_D0.Value = 106;
            comp.HPFTab = uitab(comp.OperationTabGroup);
            comp.HPFTab.Title = 'HPF';
            comp.TabGroup_2 = uitabgroup(comp.HPFTab);
            comp.IHPFTab_2 = uitab(comp.TabGroup_2);
            comp.IHPFTab_2.Title = 'IHPF';
            comp.MaskSzeLabel_20 = uilabel(comp.IHPFTab_2);
            comp.MaskSzeLabel_20.Text = 'D0';
            comp.IHPF_D0 = uieditfield(comp.IHPFTab_2, 'numeric');
            comp.IHPF_D0.Limits = [0 Inf];
            comp.IHPF_D0.Value = 106;
            comp.BHPFTab_2 = uitab(comp.TabGroup_2);
            comp.BHPFTab_2.Title = 'BHPF';
            comp.MaskSzeLabel_21 = uilabel(comp.BHPFTab_2);
            comp.MaskSzeLabel_21.Text = 'Mask Size';
            comp.BHPF_mask_size = uieditfield(comp.BHPFTab_2, 'numeric');
            comp.BHPF_mask_size.Limits = [0 Inf];
            comp.BHPF_mask_size.Value = 106;
            comp.MaskSzeLabel_22 = uilabel(comp.BHPFTab_2);
            comp.MaskSzeLabel_22.Text = 'D0';
            comp.BHPF_D0 = uieditfield(comp.BHPFTab_2, 'numeric');
            comp.BHPF_D0.Limits = [0 Inf];
            comp.BHPF_D0.Value = 106;
            comp.GHPFTab_2 = uitab(comp.TabGroup_2);
            comp.GHPFTab_2.Title = 'GHPF';
            comp.MaskSzeLabel_23 = uilabel(comp.GHPFTab_2);
            comp.MaskSzeLabel_23.Text = 'D0';
            comp.GHPF_D0 = uieditfield(comp.GHPFTab_2, 'numeric');
            comp.GHPF_D0.Limits = [0 Inf];
            comp.GHPF_D0.Value = 106;
            comp.No4Tab = uitab(comp.OperationTabGroup);
            comp.No4Tab.Title = 'No.4';
            comp.ImageDropDownLabel = uilabel(comp.No4Tab);
            comp.ImageDropDownLabel.Text = 'Image';
            comp.No4_ImageDropDown = uidropdown(comp.No4Tab);
            comp.No4_ImageDropDown.Items = {'Image 1', 'Image 2'};
            comp.No4_ImageDropDown.Value = 'Image 1';
            comp.metode = uilabel(comp.No4Tab);
            comp.metode.Text = {'Metode yang digunakan:'; '1. ...'; '2. ...'; '3. ...'};
            comp.RestorationTab = uitab(comp.OperationTabGroup);
            comp.RestorationTab.Title = 'Restoration';
            comp.TabGroup_3 = uitabgroup(comp.RestorationTab);
            comp.MinFilterTab = uitab(comp.TabGroup_3);
            comp.MinFilterTab.Title = 'Min Filter';
            comp.MaskSzeLabel_24 = uilabel(comp.MinFilterTab);
            comp.MaskSzeLabel_24.Text = 'Density';
            comp.Mif_density = uieditfield(comp.MinFilterTab, 'numeric');
            comp.Mif_density.Limits = [0 Inf];
            comp.Mif_density.Value = 106;
            comp.MaskSzeLabel_31 = uilabel(comp.MinFilterTab);
            comp.MaskSzeLabel_31.Text = 'Mask Size (m) ';
            comp.MiF_mask_size_m = uieditfield(comp.MinFilterTab, 'numeric');
            comp.MiF_mask_size_m.Limits = [0 Inf];
            comp.MiF_mask_size_m.Value = 106;
            comp.MaskSzeLabel_32 = uilabel(comp.MinFilterTab);
            comp.MaskSzeLabel_32.Text = 'Mask Size (n) ';
            comp.MiF_mask_size_n = uieditfield(comp.MinFilterTab, 'numeric');
            comp.MiF_mask_size_n.Limits = [0 Inf];
            comp.MiF_mask_size_n.Value = 106;
            comp.MaxFilterTab = uitab(comp.TabGroup_3);
            comp.MaxFilterTab.Title = 'Max Filter';
            comp.MaskSzeLabel_33 = uilabel(comp.MaxFilterTab);
            comp.MaskSzeLabel_33.Text = 'Density';
            comp.MaF_density = uieditfield(comp.MaxFilterTab, 'numeric');
            comp.MaF_density.Limits = [0 Inf];
            comp.MaF_density.Value = 106;
            comp.MaskSzeLabel_34 = uilabel(comp.MaxFilterTab);
            comp.MaskSzeLabel_34.Text = 'Mask Size (m) ';
            comp.MaF_mask_size_m = uieditfield(comp.MaxFilterTab, 'numeric');
            comp.MaF_mask_size_m.Limits = [0 Inf];
            comp.MaF_mask_size_m.Value = 106;
            comp.MaskSzeLabel_35 = uilabel(comp.MaxFilterTab);
            comp.MaskSzeLabel_35.Text = 'Mask Size (n) ';
            comp.MaF_mask_size_n = uieditfield(comp.MaxFilterTab, 'numeric');
            comp.MaF_mask_size_n.Limits = [0 Inf];
            comp.MaF_mask_size_n.Value = 106;
            comp.MedianFilterTab = uitab(comp.TabGroup_3);
            comp.MedianFilterTab.Title = 'Median Filter';
            comp.MaskSzeLabel_39 = uilabel(comp.MedianFilterTab);
            comp.MaskSzeLabel_39.Text = 'Density';
            comp.MeF_density = uieditfield(comp.MedianFilterTab, 'numeric');
            comp.MeF_density.Limits = [0 Inf];
            comp.MeF_density.Value = 106;
            comp.MaskSzeLabel_40 = uilabel(comp.MedianFilterTab);
            comp.MaskSzeLabel_40.Text = 'Mask Size (m) ';
            comp.MeF_mask_size_m = uieditfield(comp.MedianFilterTab, 'numeric');
            comp.MeF_mask_size_m.Limits = [0 Inf];
            comp.MeF_mask_size_m.Value = 106;
            comp.MaskSzeLabel_41 = uilabel(comp.MedianFilterTab);
            comp.MaskSzeLabel_41.Text = 'Mask Size (n) ';
            comp.MeF_mask_size_n = uieditfield(comp.MedianFilterTab, 'numeric');
            comp.MeF_mask_size_n.Limits = [0 Inf];
            comp.MeF_mask_size_n.Value = 106;
            comp.ArithmeticMeanFilterTab = uitab(comp.TabGroup_3);
            comp.ArithmeticMeanFilterTab.Title = 'Arithmetic Mean Filter';
            comp.MaskSzeLabel_45 = uilabel(comp.ArithmeticMeanFilterTab);
            comp.MaskSzeLabel_45.Text = 'Density';
            comp.AMF_density = uieditfield(comp.ArithmeticMeanFilterTab, 'numeric');
            comp.AMF_density.Limits = [0 Inf];
            comp.AMF_density.Value = 106;
            comp.MaskSzeLabel_46 = uilabel(comp.ArithmeticMeanFilterTab);
            comp.MaskSzeLabel_46.Text = 'Mask Size (m) ';
            comp.AMF_mask_size_m = uieditfield(comp.ArithmeticMeanFilterTab, 'numeric');
            comp.AMF_mask_size_m.Limits = [0 Inf];
            comp.AMF_mask_size_m.Value = 106;
            comp.MaskSzeLabel_47 = uilabel(comp.ArithmeticMeanFilterTab);
            comp.MaskSzeLabel_47.Text = 'Mask Size (n) ';
            comp.AMF_mask_size_n = uieditfield(comp.ArithmeticMeanFilterTab, 'numeric');
            comp.AMF_mask_size_n.Limits = [0 Inf];
            comp.AMF_mask_size_n.Value = 106;
            comp.GeometricMeanFilterTab = uitab(comp.TabGroup_3);
            comp.GeometricMeanFilterTab.Title = 'Geometric Mean Filter';
            comp.MaskSzeLabel_48 = uilabel(comp.GeometricMeanFilterTab);
            comp.MaskSzeLabel_48.Text = 'Density';
            comp.GMF_density = uieditfield(comp.GeometricMeanFilterTab, 'numeric');
            comp.GMF_density.Limits = [0 Inf];
            comp.GMF_density.Value = 106;
            comp.MaskSzeLabel_49 = uilabel(comp.GeometricMeanFilterTab);
            comp.MaskSzeLabel_49.Text = 'Mask Size (m) ';
            comp.GMF_mask_size_m = uieditfield(comp.GeometricMeanFilterTab, 'numeric');
            comp.GMF_mask_size_m.Limits = [0 Inf];
            comp.GMF_mask_size_m.Value = 106;
            comp.MaskSzeLabel_50 = uilabel(comp.GeometricMeanFilterTab);
            comp.MaskSzeLabel_50.Text = 'Mask Size (n) ';
            comp.GMF_mask_size_n = uieditfield(comp.GeometricMeanFilterTab, 'numeric');
            comp.GMF_mask_size_n.Limits = [0 Inf];
            comp.GMF_mask_size_n.Value = 106;
            comp.HarmonicMeanFilterTab = uitab(comp.TabGroup_3);
            comp.HarmonicMeanFilterTab.Title = 'Harmonic Mean Filter';
            comp.MaskSzeLabel_51 = uilabel(comp.HarmonicMeanFilterTab);
            comp.MaskSzeLabel_51.Text = 'Density';
            comp.HMF_density = uieditfield(comp.HarmonicMeanFilterTab, 'numeric');
            comp.HMF_density.Limits = [0 Inf];
            comp.HMF_density.Value = 106;
            comp.MaskSzeLabel_52 = uilabel(comp.HarmonicMeanFilterTab);
            comp.MaskSzeLabel_52.Text = 'Mask Size (m) ';
            comp.HMF_mask_size_m = uieditfield(comp.HarmonicMeanFilterTab, 'numeric');
            comp.HMF_mask_size_m.Limits = [0 Inf];
            comp.HMF_mask_size_m.Value = 106;
            comp.MaskSzeLabel_53 = uilabel(comp.HarmonicMeanFilterTab);
            comp.MaskSzeLabel_53.Text = 'Mask Size (n) ';
            comp.HMF_mask_size_n = uieditfield(comp.HarmonicMeanFilterTab, 'numeric');
            comp.HMF_mask_size_n.Limits = [0 Inf];
            comp.HMF_mask_size_n.Value = 106;
            comp.ContraharmonicMeanFilterTab = uitab(comp.TabGroup_3);
            comp.ContraharmonicMeanFilterTab.Title = 'Contraharmonic Mean Filter';
            comp.MaskSzeLabel_54 = uilabel(comp.ContraharmonicMeanFilterTab);
            comp.MaskSzeLabel_54.Text = 'Density';
            comp.CMF_density = uieditfield(comp.ContraharmonicMeanFilterTab, 'numeric');
            comp.CMF_density.Limits = [0 Inf];
            comp.CMF_density.Value = 106;
            comp.MaskSzeLabel_55 = uilabel(comp.ContraharmonicMeanFilterTab);
            comp.MaskSzeLabel_55.Text = 'Mask Size (m) ';
            comp.CMF_mask_size_m = uieditfield(comp.ContraharmonicMeanFilterTab, 'numeric');
            comp.CMF_mask_size_m.Limits = [0 Inf];
            comp.CMF_mask_size_m.Value = 106;
            comp.MaskSzeLabel_56 = uilabel(comp.ContraharmonicMeanFilterTab);
            comp.MaskSzeLabel_56.Text = 'Mask Size (n) ';
            comp.CMF_mask_size_n = uieditfield(comp.ContraharmonicMeanFilterTab, 'numeric');
            comp.CMF_mask_size_n.Limits = [0 Inf];
            comp.CMF_mask_size_n.Value = 106;
            comp.MaskSzeLabel_65 = uilabel(comp.ContraharmonicMeanFilterTab);
            comp.MaskSzeLabel_65.Text = 'Q';
            comp.CMF_Q = uieditfield(comp.ContraharmonicMeanFilterTab, 'numeric');
            comp.CMF_Q.Limits = [0 Inf];
            comp.CMF_Q.Value = 106;
            comp.MidpointFilterTab = uitab(comp.TabGroup_3);
            comp.MidpointFilterTab.Title = 'Midpoint Filter';
            comp.MaskSzeLabel_58 = uilabel(comp.MidpointFilterTab);
            comp.MaskSzeLabel_58.Text = 'Density';
            comp.MPF_density = uieditfield(comp.MidpointFilterTab, 'numeric');
            comp.MPF_density.Limits = [0 Inf];
            comp.MPF_density.Value = 106;
            comp.MaskSzeLabel_59 = uilabel(comp.MidpointFilterTab);
            comp.MaskSzeLabel_59.Text = 'Mask Size (m) ';
            comp.MPF_mask_size_m = uieditfield(comp.MidpointFilterTab, 'numeric');
            comp.MPF_mask_size_m.Limits = [0 Inf];
            comp.MPF_mask_size_m.Value = 106;
            comp.MaskSzeLabel_60 = uilabel(comp.MidpointFilterTab);
            comp.MaskSzeLabel_60.Text = 'Mask Size (n) ';
            comp.MPF_mask_size_n = uieditfield(comp.MidpointFilterTab, 'numeric');
            comp.MPF_mask_size_n.Limits = [0 Inf];
            comp.MPF_mask_size_n.Value = 106;
            comp.AlphatrimmedMeanFilterTab = uitab(comp.TabGroup_3);
            comp.AlphatrimmedMeanFilterTab.Title = 'Alpha-trimmed Mean Filter';
            comp.MaskSzeLabel_61 = uilabel(comp.AlphatrimmedMeanFilterTab);
            comp.MaskSzeLabel_61.Text = 'Density';
            comp.ATMF_density = uieditfield(comp.AlphatrimmedMeanFilterTab, 'numeric');
            comp.ATMF_density.Limits = [0 Inf];
            comp.ATMF_density.Value = 106;
            comp.MaskSzeLabel_62 = uilabel(comp.AlphatrimmedMeanFilterTab);
            comp.MaskSzeLabel_62.Text = 'Mask Size (m) ';
            comp.ATMF_mask_size_m = uieditfield(comp.AlphatrimmedMeanFilterTab, 'numeric');
            comp.ATMF_mask_size_m.Limits = [0 Inf];
            comp.ATMF_mask_size_m.Value = 106;
            comp.MaskSzeLabel_63 = uilabel(comp.AlphatrimmedMeanFilterTab);
            comp.MaskSzeLabel_63.Text = 'Mask Size (n) ';
            comp.ATMF_mask_size_n = uieditfield(comp.AlphatrimmedMeanFilterTab, 'numeric');
            comp.ATMF_mask_size_n.Limits = [0 Inf];
            comp.ATMF_mask_size_n.Value = 106;
            comp.MaskSzeLabel_64 = uilabel(comp.AlphatrimmedMeanFilterTab);
            comp.MaskSzeLabel_64.Text = 'd';
            comp.ATMF_d = uieditfield(comp.AlphatrimmedMeanFilterTab, 'numeric');
            comp.ATMF_d.Limits = [0 Inf];
            comp.ATMF_d.Value = 106;
            comp.NoiseDropDownLabel = uilabel(comp.RestorationTab);
            comp.NoiseDropDownLabel.Text = 'Noise';
            comp.NoiseDropDown = uidropdown(comp.RestorationTab);
            comp.NoiseDropDown.Items = {'Salt & Pepper', 'Gaussian'};
            comp.NoiseDropDown.Value = 'Salt & Pepper';
            comp.NotchFilterTab = uitab(comp.OperationTabGroup);
            comp.NotchFilterTab.Title = 'Notch Filter';
            comp.ImageDropDown_2Label = uilabel(comp.NotchFilterTab);
            comp.ImageDropDown_2Label.Text = 'Image';
            comp.Notch_ImageDropDown = uidropdown(comp.NotchFilterTab);
            comp.Notch_ImageDropDown.Items = {'Image 1', 'Image 2', 'Image 3', 'Image 4', 'Image 5', 'Image 6'};
            comp.Notch_ImageDropDown.Value = 'Image 1';
            comp.MaskSzeLabel_66 = uilabel(comp.NotchFilterTab);
            comp.MaskSzeLabel_66.Text = 'Density';
            comp.ATMF_density_2 = uieditfield(comp.NotchFilterTab, 'numeric');
            comp.ATMF_density_2.Limits = [0 Inf];
            comp.ATMF_density_2.Value = 106;
            comp.MotionDebluringTab = uitab(comp.OperationTabGroup);
            comp.MotionDebluringTab.Title = 'Motion Debluring';
            comp.PSFDropDownLabel = uilabel(comp.MotionDebluringTab);
            comp.PSFDropDownLabel.Text = 'PSF';
            comp.PSFDropDown = uidropdown(comp.MotionDebluringTab);
            comp.PSFDropDown.Items = {'Gaussian', 'Motion'};
            comp.PSFDropDown.Value = 'Gaussian';
            comp.NSRSliderLabel = uilabel(comp.MotionDebluringTab);
            comp.NSRSliderLabel.Text = 'NSR';
            comp.NSRSlider = uislider(comp.MotionDebluringTab);
            comp.NSRSlider.Limits = [0 1];
            comp.ImageDisplayPanel = uipanel(comp);
            comp.ImageDisplayPanel.Title = 'Image display';
            comp.OutputImage = uiaxes(comp.ImageDisplayPanel);
            title(comp.OutputImage, 'Output image')
            comp.OutputImage.XTick = [];
            comp.OutputImage.YTick = [];
            comp.InputImage = uiaxes(comp.ImageDisplayPanel);
            title(comp.InputImage, 'Input image')
            comp.InputImage.XTick = [];
            comp.InputImage.YTick = [];
            comp.OperationDropDownLabel = uilabel(comp);
            comp.OperationDropDownLabel.Text = 'Operation';
            comp.OperationDropDown = uidropdown(comp);
            comp.OperationDropDown.Items = {'Brightness', 'Negative', 'Logarithm transform', 'Power transform', 'Contrast stretching', 'Histogram equalization', 'Histogram matching'};
            comp.OperationDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @OperationDropDownValueChanged, true);
            comp.OperationDropDown.Value = 'Brightness';
            comp.ApplyButton = uibutton(comp, 'push');
            comp.ApplyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ApplyButtonPushed, true);
            comp.ApplyButton.Enable = 'off';
            comp.ApplyButton.Text = 'Apply';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end